classdef scout_v5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        panMeasureOpt            matlab.ui.container.Panel
        txtHelpSpeed             matlab.ui.control.TextArea
        txtHelpMLlength          matlab.ui.control.TextArea
        txtSpeed                 matlab.ui.control.NumericEditField
        btnHelpSpeed             matlab.ui.control.StateButton
        lblSpeed                 matlab.ui.control.Label
        btnHelpMLlength          matlab.ui.control.StateButton
        lblMLlength              matlab.ui.control.Label
        ddLengthOpt              matlab.ui.control.DropDown
        txtNumROI                matlab.ui.control.NumericEditField
        NumberofSubregionsLabel  matlab.ui.control.Label
        txtInterROIPct           matlab.ui.control.NumericEditField
        IntercondylarROILabel    matlab.ui.control.Label
        btnMeasure               matlab.ui.control.Button
        btnAccept                matlab.ui.control.StateButton
        panROI                   matlab.ui.container.Panel
        btnAdjustNotch           matlab.ui.control.Button
        btnAdjustContours        matlab.ui.control.Button
        lblContours              matlab.ui.control.Label
        btnDraw                  matlab.ui.control.Button
        ddNumContours            matlab.ui.control.DropDown
        panSave                  matlab.ui.container.Panel
        txtHelpProcess           matlab.ui.control.TextArea
        txtHelpCompile           matlab.ui.control.TextArea
        btnHelpProcess           matlab.ui.control.StateButton
        btnHelpCompile           matlab.ui.control.StateButton
        ddProcess                matlab.ui.control.DropDown
        btnSave                  matlab.ui.control.Button
        btnCompile               matlab.ui.control.Button
        panResults               matlab.ui.container.Panel
        btnColors                matlab.ui.control.Button
        btnMeasuresNone          matlab.ui.control.Button
        btnMeasuresAll           matlab.ui.control.Button
        listMeasures             matlab.ui.control.ListBox
        panEnhance               matlab.ui.container.Panel
        txtHelpImage             matlab.ui.control.TextArea
        btnHelpImage             matlab.ui.control.StateButton
        lblFilters               matlab.ui.control.Label
        ddFilters                matlab.ui.control.DropDown
        chkViewOriginal          matlab.ui.control.CheckBox
        sldContrast              matlab.ui.control.Slider
        ContrastSliderLabel      matlab.ui.control.Label
        panSettings              matlab.ui.container.Panel
        txtHelpCorners           matlab.ui.control.TextArea
        ddImageType              matlab.ui.control.DropDown
        btnHelpCorners           matlab.ui.control.StateButton
        btnCorners               matlab.ui.control.Button
        chkViewFull              matlab.ui.control.CheckBox
        btnCrop                  matlab.ui.control.Button
        Image                    matlab.ui.control.Image
        btnHome                  matlab.ui.control.StateButton
        btnPan                   matlab.ui.control.StateButton
        btnZoom                  matlab.ui.control.StateButton
        lblImages                matlab.ui.control.Label
        lblSubjects              matlab.ui.control.Label
        btnLoad                  matlab.ui.control.Button
        chkAutoLoad              matlab.ui.control.CheckBox
        ddImages                 matlab.ui.control.DropDown
        txtStudyFolder           matlab.ui.control.EditField
        ddSubs                   matlab.ui.control.DropDown
        btnChooseStudy           matlab.ui.control.Button
        txtFeedback              matlab.ui.control.Label
        axes                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        D; % cropped
        D_original; % full image
        params; % main app
        D_grayscale; % grayscale image
        roi_1 % near quad tendon-cartilage
        roi_2 % far quad tendon-cartilage
        roi_3 % near cartilage-bone
        pos_1 % near quad tendon-cartilage
        pos_2 % far quad tendon-cartilage
        pos_3 % near cartilage-bone
        roi_notch
        pos_notch
        roi_corner_left
        roi_corner_right
        pos_corner_left
        pos_corner_right
    end

    methods (Access = private)

        function accept_contours(app, roi_1, roi_2, roi_3, pos_1, pos_2, pos_3)
            delete(roi_2);
            delete(roi_3);
            app.btnAccept.Visible = 'off';

            % round data
            posxr_2 = round(pos_2(:, 1));
            posyr_2 = round(pos_2(:, 2));
            posxr_3 = round(pos_3(:, 1));
            posyr_3 = round(pos_3(:, 2));

            % if drawn from right to left
            if posxr_2(1) > posxr_2(end)
                posxr_2 = flip(posxr_2, 1);
                posyr_2 = flip(posyr_2, 1);
            end

            if posxr_3(1) > posxr_3(end)
                posxr_3 = flip(posxr_3, 1);
                posyr_3 = flip(posyr_3, 1);
            end

            x_left = max(min(posxr_2), min(posxr_3));
            x_right = min(max(posxr_2), max(posxr_3));

            if ~strcmp(app.ddNumContours.Value,'Two')
                delete(roi_1);
                posxr_1 = round(pos_1(:, 1));
                posyr_1 = round(pos_1(:, 2));

                if posxr_1(1) > posxr_1(end)
                    posxr_1 = flip(posxr_1, 1);
                    posyr_1 = flip(posyr_1, 1);
                end

                x_left = max(x_left, min(posxr_1));
                x_right = min(x_right, max(posxr_1));
            end

            posx_2 = (x_left:x_right)';
            posy_2 = round(interp1q(posxr_2, posyr_2, posx_2)); % interp1, spline
            posx_3 = posx_2;
            posy_3 = round(interp1q(posxr_3, posyr_3, posx_3)); % interp1, spline

            if ~strcmp(app.ddNumContours.Value,'Two')
                posx_1 = posx_2;
                posy_1 = round(interp1q(posxr_1, posyr_1, posx_1)); % spline
            else
                posx_1 = [];
                posy_1 = [];
            end

            % get intercondylar notch
            if strcmp(app.ddImageType.Value, 'Transverse')
                yv = max(posy_3);
            else
                yv = min(posy_3);
            end
            xv_all = find(posy_3 == yv);
            xv = round((max(xv_all) + min(xv_all)) / 2);
            intercon_notch_x = xv + posx_3(1) - 1;
            intercon_notch_y = yv;
            
            % Update lists
            app.listMeasures.Items = {'Contours','Intercondylar Notch','Image Corners'};
            app.listMeasures.Value = {'Contours','Intercondylar Notch','Image Corners'};
            paramSave(app, 'contour_1', [posx_1 posy_1]);
            paramSave(app, 'contour_2', [posx_2 posy_2]);
            paramSave(app, 'contour_3', [posx_3 posy_3]);
            paramSave(app, 'intercondylar_notch', [intercon_notch_x intercon_notch_y]);

            refreshAxes(app, app.axes)
            app.txtFeedback.Text = '';
            tracing_state(app, 'on')
        end

        function s = build_struct(app, field_names, value) %#ok<INUSL>
            s = [];
            for k = 1:numel(field_names)
                s.(field_names{k}) = value;
            end
        end

        function clear_measures(app, clear_notch)
            if (nargin < 2), clear_notch = true; end
            if (clear_notch), paramSave(app, 'intercondylar_notch', []); end

            paramSave(app, 'regions', [])

            app.listMeasures.Items = {'Contours','Image Corners'};
            app.listMeasures.Value = {'Contours','Image Corners'};
        end

        function [sulcus_angle, sulcus_depth] = femoral_trochlear(app, xy)
            y_res = paramGet(app, 'resolutiony', double.empty(0, 2));
            x = xy(:,1);
            y = xy(:,2);
            yv = max(y);
            xv_all = find(y == yv);
            xv = round((max(xv_all) + min(xv_all)) / 2);
            apex_x = xv + x(1) - 1;
            apex_y = yv;

            % Find the left summit
            y_sub = y(1:xv);
            ys = min(y_sub);
            xs_all = find(y_sub == ys);
            xs = round((max(xs_all) + min(xs_all)) / 2);
            left_summit_x = xs + x(1) - 1;
            left_summit_y = ys;

            % Find the right summit
            y_sub = y(xv:end);
            ys = min(y_sub);
            xs_all = find(y_sub == ys);
            xs = round((max(xs_all) + min(xs_all)) / 2);
            right_summit_x = xs + (xv - 1) + (x(1) - 1);
            right_summit_y = ys;

            px = [left_summit_x, apex_x, right_summit_x];
            py = [left_summit_y, apex_y, right_summit_y];

            v1 = [px(2),py(2)]-[px(1),py(1)];
            v2 = [px(3),py(3)]-[px(2),py(2)];
            sulcus_angle = 180 - (acos(sum(v1.*v2)/(norm(v1)*norm(v2))) * (180/pi));

            % Get intersection
            k = ((right_summit_y-left_summit_y) * (apex_x - left_summit_x) - (right_summit_x-left_summit_x) * (apex_y-left_summit_y)) ...
                / ((right_summit_y-left_summit_y)^2 + (right_summit_x-left_summit_x)^2);
            int_x = apex_x - k * (right_summit_y-left_summit_y);
            int_y = apex_y + k * (right_summit_x-left_summit_x);

            sulcus_depth = (sqrt((int_x-apex_x)^2 + (int_y-apex_y)^2))*y_res;
        end

        function [subject_folder_path, subject] = getSubject(app)
            [~, subject] = fileparts(fullfile(app.txtStudyFolder.Value,app.ddSubs.Items(app.ddSubs.Value)));
            subject_folder_path = fullfile(app.txtStudyFolder.Value,subject);
        end

        function value = paramGet(app, param_name, default, matfile_path)
            if (nargin < 3) || (isempty(default)), default = []; end
            if (nargin < 4) || (isempty(matfile_path)), matfile_path = app.params.path; end

            f = matfile(matfile_path, "Writable", true);
            if (any(ismember(who(f), param_name)))
                value = f.(param_name);
            else
                f.(param_name) = default;
                value = default;
            end
        end

        function paramSave(app, param_name, value)
            f = matfile(app.params.path, "Writable", true);
            if (nargin < 3)
                if (isfield(app.params, param_name))
                    value = app.params.(param_name);
                else
                    value = [];
                end
            end
            f.(param_name) = value;
            app.params.(param_name) = value;
        end

        function process_single_file(app, m_file_path)
            if (nargin < 2), m_file_path = app.params.path; end
            [parent,~,~] = fileparts(m_file_path);
            [~,sub,~] = fileparts(parent);
            im = string(app.ddImages.Items(app.ddImages.Value));

            % Load regions
            r = paramGet(app, 'regions');

            % Check that the file has been processed
            if (isempty(r))
                if (app.ddProcess.Value == 1)
                    errordlg('Need to take measurements first','Error');
                else
                    app.txtFeedback.Text = strcat({'Skipping: '}, sub, {' - '}, im);
                end

                return
            end
            app.txtFeedback.Text = strcat('Processing',{': '},im);
            drawnow

            % Construct template
            interfaces = {'near', 'far'};
            types = {'cart', 'tissue'};
            measure = {'thickness', 'thickness_corrected', 'ei', 'ei_norm'};
            stats = {'mean', 'min', 'max', 'median', 'pct_error'};
            s_stats = build_struct(app, stats, 0);
            s_measure = build_struct(app, measure, s_stats);
            s_types = build_struct(app, types, s_measure);
            s.template = build_struct(app, interfaces, s_types);

            % Construct regions and subregions
            regions = {'med', 'lat', 'inter'};
            n_reg = r.nr;
            n_sub = r.ns;
            x_reg = r.r;
            x_sub = r.s;
            for kr = 1:n_reg
                s.(regions{kr}).whole = s.template;
                for m = 1:n_sub, s.(regions{kr}).sub(m) = s.template; end
            end

            % Get parameters
            res_y = paramGet(app, 'resolutiony');
            speed_correction_factor = paramGet(app, 'speed_correction_factor');
            max_I = max(app.D_grayscale(:));
            min_I = min(app.D_grayscale(:));
            delta_I = double(max_I - min_I);
            top_left_corner = paramGet(app, 'top_left_corner', double.empty(0, 2));
            y_c_top = max(min(top_left_corner(2), size(app.D, 1)), 1);
            c_top = zeros(numel(x_reg), 2);
            c_top(:, 1) = 1:numel(x_reg);
            c_top(:, 2) = y_c_top;

            % Iterate over the regions and subregions
            for nr = 1:n_reg
                for ns = 1:(n_sub + 1)
                    % Are we processing the whole region?
                    is_whole = (ns == n_sub + 1);

                    % Retrieve the x range for this region and subregion
                    if (is_whole)
                        x_range = find((x_reg == nr));
                    else
                        x_range = find((x_reg == nr) & (x_sub == ns));
                    end

                    % Proceed by interface (near and far)
                    for nc = 1:2
                        % Find the top contour for this interface
                        % (1 for near, 2 for far)
                        c = paramGet(app, ['contour_' num2str(nc)], []);
                        if (isempty(c)), continue, end
                        ca = zeros(size(x_reg));
                        ca(c(:, 1)) = c(:, 2);

                        % Cart (1) or tissue (2)
                        for nt = 1:2
                            % Get second contour
                            if (nt == 1)
                                % Cart: use bottom
                                c = paramGet(app, 'contour_3', []);
                            else
                                % Tissue: use top
                                c = c_top;
                            end
                            if (isempty(c)), return, end
                            cb = zeros(size(x_reg));
                            cb(c(:, 1)) = c(:, 2);

                            % Get thickness
                            t = [];
                            [~, dist_px] = distance2curve(...
                                [x_range, ca(x_range)], ...
                                [x_range, cb(x_range)], 'spline');
                            dist_mm = dist_px * res_y * speed_correction_factor;
                            t.min = min(dist_mm);
                            t.mean = mean(dist_mm);
                            t.max = max(dist_mm);
                            t.median = median(dist_mm);
                            t.pct_error = 0;
                            if (is_whole)
                                s.(regions{nr}).whole.(interfaces{nc}).(types{nt}).thickness = t;
                            else
                                s.(regions{nr}).sub(ns).(interfaces{nc}).(types{nt}).thickness = t;
                            end

                            % Correct thickness for refraction
                            if (nt == 1)
                                t = [];
                                [dist_corrected_px, t.pct_error] = ...
                                    refraction_correction(app, x_range, ca(x_range), dist_px);
                                dist_corrected_mm = dist_corrected_px * res_y * speed_correction_factor;
                                t.min = min(dist_corrected_mm);
                                t.mean = mean(dist_corrected_mm);
                                t.max = max(dist_corrected_mm);
                                t.median = median(dist_corrected_mm);
                                if (is_whole)
                                    s.(regions{nr}).whole.(interfaces{nc}).(types{nt}).thickness_corrected = t;
                                else
                                    s.(regions{nr}).sub(ns).(interfaces{nc}).(types{nt}).thickness_corrected = t;
                                end
                            end

                            % Get echo intensity
                            I = [];
                            for k = 1:numel(x_range)
                                y_top = round(min(ca(x_range(k)), cb(x_range(k))));
                                y_bottom = round(max(ca(x_range(k)), cb(x_range(k))));
                                I = [I ; app.D_grayscale(y_top:y_bottom, x_range(k))];  %#ok<AGROW>
                            end
                            t = [];
                            t.min = min(I);
                            t.mean = mean(I);
                            t.max = max(I);
                            t.median = median(I);
                            t.pct_error = 0;
                            if (is_whole)
                                s.(regions{nr}).whole.(interfaces{nc}).(types{nt}).ei = t;
                            else
                                s.(regions{nr}).sub(ns).(interfaces{nc}).(types{nt}).ei = t;
                            end

                            % Normalized echo intensity
                            I = double(I - min_I);
                            if (delta_I > 0), I = I / delta_I; end
                            I = 100 * I;
                            t = [];
                            t.min = min(I);
                            t.mean = mean(I);
                            t.max = max(I);
                            t.median = median(I);
                            t.pct_error = 0;
                            if (is_whole)
                                s.(regions{nr}).whole.(interfaces{nc}).(types{nt}).ei_norm = t;
                            else
                                s.(regions{nr}).sub(ns).(interfaces{nc}).(types{nt}).ei_norm = t;
                            end
                        end
                    end
                end
            end

            % Save data
            paramSave(app, 'limb_output_data', s)

            % Save processing parameters
            pc = [];
            pc.processed = true;
            pc.num_of_contours = app.ddNumContours.Value;
            pc.intercondylar_percentage = app.txtInterROIPct.Value;
            pc.number_ROIs = app.txtNumROI.Value;
            pc.ML_length = app.ddLengthOpt.Value;
            pc.speed_correction_factor = app.txtSpeed.Value;
            paramSave(app, 'processing_params', pc)
        end

        function [dt, pct_error] = refraction_correction(app, ...
                contour_x, contour_y, dist_px)
            V_speed = app.txtSpeed.Value;

            [TF,S1,S2] = ischange(contour_y,'linear', 'MaxNumChanges', 4);
            segline = round(S1.*contour_x + S2);
            n_change = [1 ; find(TF == 1) ; numel(contour_y)];
            contour_x_reg = [ones(size(contour_x)) contour_x];
            AOI = zeros(size(contour_y));
            for k = 1:(numel(n_change) - 1)
                x_range = (n_change(k) + 1):(n_change(k+1) - 1);
                if (numel(x_range) < 2)
                    AOI(x_range) = 0;
                else
                    m = contour_x_reg(x_range, :) \ segline(x_range);
                    AOI(x_range) = (atan(m(2)))*180/pi;
                end
            end

            AOI(1) = AOI(2);
            for k = 2:(numel(n_change) - 1)
                AOI(n_change(k)) = (AOI(n_change(k) - 1) + AOI(n_change(k) + 1)) / 2;
            end
            AOI(end) = AOI(end-1);
            AOR = asind(V_speed*(sind(AOI)));
            dt = dist_px .* (cosd(AOR) ./ cosd(AOI));
            delta = abs(dt - dist_px);
            pct_error = mean(delta ./ dist_px); % Percent by element
        end

        function refreshAxes(app, axesTarget, suppress_measures, I)
            if ((nargin < 2) || isempty(axesTarget)), axesTarget = app.axes; end
            if (nargin < 3), suppress_measures = false; end
            if (nargin < 4)
                if (app.chkViewFull.Value == 0)
                    I = app.D;
                else
                    I = app.D_original;
                end
            end

            % Edit contrast based on user selection
            % Get brightness value from slider
            contrastFactor = app.sldContrast.Value*.01 + 0.5;
            I = uint8(double(I) * contrastFactor);

            % Filter options
            switch app.ddFilters.Value
                case 'None'
                    % Do nothing (I = I)
                case 'Auto Adjust Contrast'
                    gs = 0.2989 * I(:, :, 1, :) + 0.5870 * I(:, :, 2, :) + 0.1140 * I(:, :, 3, :);
                    I = adapthisteq(gs,'NumTiles',[8 8],'ClipLimit',0.002);
                case 'Butterworth'
                    gs = 0.2989 * I(:, :, 1, :) + 0.5870 * I(:, :, 2, :) + 0.1140 * I(:, :, 3, :);
                    I = butter_MW(gs,5,180,4);
                case 'Auto+Butterworth'
                    gs = 0.2989 * I(:, :, 1, :) + 0.5870 * I(:, :, 2, :) + 0.1140 * I(:, :, 3, :);
                    gs = adapthisteq(gs,'NumTiles',[8 8],'ClipLimit',0.002);
                    I = butter_MW(gs,5,180,4);
            end

            % Display image
            imagesc(axesTarget, I);
            colormap(axesTarget,'bone')
            axis(axesTarget, 'equal')
            app.axes.XTick = [];

            if (~suppress_measures)
                hold(axesTarget, 'on')

                % Get colors
                notch_color = paramGet(app, 'notch_color', 'r');
                contour_color = paramGet(app, 'contour_color', 'y');
                region_color = paramGet(app, 'region_color',  [{'r'},{'g'},{'b'}]);
                corner_color = paramGet(app, 'corner_color', 'r');

                % Contours
                if (any(ismember(app.listMeasures.Value, 'Contours')))
                    contour_1 = paramGet(app, 'contour_1', double.empty(0, 2));
                    contour_2 = paramGet(app, 'contour_2', double.empty(0, 2));
                    contour_3 = paramGet(app, 'contour_3', double.empty(0, 2));
                    if ~isempty(contour_1); scatter(axesTarget, contour_1(:,1), contour_1(:,2), 1, contour_color,'filled'); end
                    scatter(axesTarget, contour_2(:,1), contour_2(:,2), 1, contour_color,'filled')
                    scatter(axesTarget, contour_3(:,1), contour_3(:,2), 1, contour_color,'filled')
                end

                % Intercondylar Notch
                if (any(ismember(app.listMeasures.Value, 'Intercondylar Notch')))
                    notch = paramGet(app, 'intercondylar_notch', double.empty(0, 2));
                    scatter(axesTarget, notch(1), notch(2), 18, notch_color,'filled')
                end

                % Regions
                if (any(ismember(app.listMeasures.Value, 'Regions')))
                    r = paramGet(app, 'regions');
                    if (~isempty(r))
                        p = r.patch;
                        for kc = 1:2
                            if (isempty(p{1, 1, kc})), continue, end
                            for kr = 1:3
                                for ks = 1:size(p, 2)
                                    pts = p{kr, ks, kc};
                                    if (~isempty(pts))
                                        patch(axesTarget, pts(:, 1), pts(:, 2), ...
                                            region_color{kr}, 'FaceAlpha', 0.35, ...
                                            'EdgeColor', region_color{kr});
                                    end
                                end
                            end
                        end
                    end
                end

                if (any(ismember(app.listMeasures.Value, 'Image Corners')))
                    left_corner = paramGet(app, 'top_left_corner', double.empty(0, 2));
                    right_corner = paramGet(app, 'top_right_corner', double.empty(0, 2));

                    scatter(app.axes, left_corner(1), left_corner(2), 30, corner_color,'+', 'Linewidth', 2)
                    scatter(app.axes, right_corner(1), right_corner(2), 30, corner_color,'+', 'Linewidth', 2)
                end

                hold(axesTarget, 'off')
            end

            % Crop
            crop = [1 size(I, 2) 1 size(I, 1)];
            if (app.chkViewFull.Value == 0), crop = paramGet(app, 'crop', crop); end
            axesTarget.XLim = [crop(1) crop(2)];
            axesTarget.YLim = [crop(3) crop(4)];

            app.axes.Toolbar.Visible = 'off';
            app.txtFeedback.Text = '';
            drawnow
        end

        function reset_image_buttons(app, btn, state)
            app.axes.Visible = 'off';
            if state == 1
                switch btn
                    case 'pan'
                        pan(app.axes,'on')
                        zoom(app.axes,'off')
                        app.btnZoom.Value = 0;
                        app.btnHome.Value = 0;
                    case 'zoom'
                        zoom(app.axes,'on')
                        pan(app.axes,'off')
                        app.btnPan.Value = 0;
                        app.btnHome.Value = 0;
                    case 'home'
                        zoom(app.axes,'off')
                        pan(app.axes,'off')
                        app.btnPan.Value = 0;
                        app.btnZoom.Value = 0;
                        refreshAxes(app);
                        app.btnHome.Value = 0;
                end
            elseif state == 0
                switch btn
                    case 'pan'
                        pan(app.axes,'off')
                        zoom(app.axes,'off')
                        app.btnZoom.Value = 0;
                        app.btnHome.Value = 0;
                    case 'zoom'
                        zoom(app.axes,'off')
                        pan(app.axes,'off')
                        app.btnPan.Value = 0;
                        app.btnHome.Value = 0;
                    case 'home'
                        zoom(app.axes,'off')
                        pan(app.axes,'off')
                        app.btnPan.Value = 0;
                        app.btnZoom.Value = 0;
                end
            end
            app.axes.Toolbar.Visible = 'off';
        end

        function show_label(~, src, evt)
            p = round(evt.CurrentPosition,0);
            position_label = strcat('X: ', mat2str(p(1)), ' Y: ', mat2str(p(2)));
            src.Label = position_label;
        end

        function tracing_state(app, state, check_exception)
            if nargin <= 2; check_exception = false; end
            app.btnCrop.Enable = state;
            app.btnCorners.Enable = state;
            app.btnAdjustContours.Enable = state;
            app.btnAdjustNotch.Enable = state;
            app.btnDraw.Enable = state;
            app.btnCompile.Enable = state;
            app.btnChooseStudy.Enable = state';
            app.btnLoad.Enable = state;
            app.btnColors.Enable = state;
            app.btnHelpCorners.Enable = state;
            app.btnHelpImage.Enable = state;
            app.btnHelpMLlength.Enable = state;
            app.btnHelpSpeed.Enable = state;
            app.btnHelpProcess.Enable = state;
            app.btnHelpCompile.Enable = state;
            app.btnMeasuresAll.Enable = state;
            app.btnMeasuresNone.Enable = state;
            app.btnMeasure.Enable = state;
            app.btnSave.Enable  = state;
            app.chkViewFull.Enable = state;
            app.chkAutoLoad.Enable = state;
            app.chkViewOriginal.Enable = state;
            app.ddFilters.Enable  = state;
            app.ddLengthOpt.Enable  = state;
            app.ddNumContours.Enable  = state;
            app.ddProcess.Enable  = state;
            app.ddImages.Enable = state;
            app.ddSubs.Enable = state;
            app.IntercondylarROILabel.Enable = state;
            app.NumberofSubregionsLabel.Enable  = state;
            app.ContrastSliderLabel.Enable = state;
            app.sldContrast.Enable = state;
            app.listMeasures.Enable = state;
            app.lblContours.Enable = state;
            app.lblFilters.Enable = state;
            app.lblMLlength.Enable = state;
            app.lblSpeed.Enable = state;
            app.txtInterROIPct.Enable = state;
            app.txtNumROI.Enable = state;
            app.txtStudyFolder.Enable = state;
            app.txtSpeed.Enable = state;
            app.ddImageType.Enable = state;

            if (check_exception)
                if isempty(paramGet(app, 'contour_2', double.empty(0, 2)))
                    app.btnMeasure.Enable  = 'off';
                end
            end
        end
        
        function find_top_image_corners(app)
            try 
                %% Find top of image
                edgesImage = edge(app.D_grayscale, 'Sobel', 'horizontal');
    
                overlayImage = cat(3, app.D_grayscale, app.D_grayscale, app.D_grayscale);
                overlayImage(:,:,1) = overlayImage(:,:,1) + uint8(edgesImage) * 255;  % Red channel
                overlayImage(:,:,2) = overlayImage(:,:,2) - uint8(edgesImage) * 255;  % Green channel
                overlayImage(:,:,3) = overlayImage(:,:,3) - uint8(edgesImage) * 255;  % Blue channel
    
                % Detect Horizontal lines in image
                [H, theta, rho] = hough(edgesImage); 
                peaks = houghpeaks(H, 5, 'threshold', ceil(0.3 * max(H(:))));
                lines = houghlines(edgesImage, theta, rho, peaks, 'FillGap', 5, 'MinLength', 7);
                max_len = 0;
    
                % Loop through the detected lines to find the longest one
                for k = 1:length(lines)
                    xy = [lines(k).point1; lines(k).point2];
                    len = norm(lines(k).point1 - lines(k).point2);
                    if (len > max_len)
                        max_len = len;
                        xy_long = xy;
                    end
                end
    
                top = xy_long(1,2);
                
                % Check to make sure top-1 is black. With a thick white
                % borded, the above was not detecting the very top pixel
                bwImage = imbinarize(app.D_grayscale, 'adaptive', 'Sensitivity', 0.4); 
                x_coords = xy_long(:,1);
                adjusted = false;

                while ~adjusted
                    is_all_black = true;

                    for x = min(x_coords):max(x_coords)
                        if bwImage(top-1, x) ~= 0
                            is_all_black = false;
                            break;
                        end
                    end

                    if is_all_black
                        adjusted = true;
                    else
                        top = top - 1;
                    end
                end

                % Remove words from image
                ocr_results = ocr(bwImage);  % Canny edge detection
                wordboxes = ocr_results.WordBoundingBoxes;
    
                [h, w] = size(app.D_grayscale);
                maxWidth = w*.25;
                maxHeight = h*.2;
    
                textMask = false(size(app.D_grayscale));
    
                for i = 1:size(wordboxes, 1)
                    bbox = wordboxes(i, :);
                    boxWidth = bbox(3);
                    boxHeight = bbox(4);
    
                    if boxWidth <= maxWidth && boxHeight <= maxHeight    
                        x1 = max(1, bbox(1)); 
                        y1 = max(1, bbox(2));  
                        x2 = min(size(app.D_grayscale, 2), bbox(1) + bbox(3) - 1);  
                        y2 = min(size(app.D_grayscale, 1), bbox(2) + bbox(4) - 1);  
                        textMask(y1:y2, x1:x2) = true;  
                    end
                end
                I_clean = bwImage;
                I_clean(textMask) = 0;  
                
                % Special work around for GE symbol next to top left of image 
                ge_template = bwImage;
                ge_template(:, 75:end) = 0;  % x
                ge_template(1:60, :) = 0;  % y
                ge_template(80:end, :) = 0;  % y

                num_pixels = nnz(ge_template);
                if num_pixels == 171
                    I_clean(ge_template) = 0;
                end

                % Find Left and Right Corner
                x_half = round(size(I_clean, 2) / 2);
                nonzero_indices_for_left_corner = find(I_clean(top+1, 1:x_half) ~= 0);
                min_consecutive = 10;
                left_corner = -1;
    
                for i = 1:(length(nonzero_indices_for_left_corner) - min_consecutive + 1)
                    if nonzero_indices_for_left_corner(i + min_consecutive - 1) - nonzero_indices_for_left_corner(i) == min_consecutive - 1
                        left_corner = nonzero_indices_for_left_corner(i);
                        break;
                    end
                end
                
                nonzero_indices_for_right_corner = find(I_clean(top+1, end:-1:x_half) ~= 0);
                right_corner = -1;
                
                for i = 1:(length(nonzero_indices_for_right_corner) - min_consecutive + 1)
                    if nonzero_indices_for_right_corner(i + min_consecutive - 1) - nonzero_indices_for_right_corner(i) == min_consecutive - 1
                        right_corner = size(I_clean, 2) - nonzero_indices_for_right_corner(i) + 1;  % Convert the index to match the original coordinate system
                        break;
                    end
                end
            catch
                top = 100;
                left_corner = app.axes.XLim(2)*0.1;
                right_corner = app.axes.XLim(2)*0.9; 
            end

            paramSave(app, 'top_left_corner', [left_corner top+1])
            paramSave(app, 'top_right_corner', [right_corner top+1])

            app.listMeasures.Items = {'Image Corners'};
            app.listMeasures.Value = {'Image Corners'};
        end
        
        function [tissue_img, cart_img] = get_image_masks(app)
            
            % extract relevant items
            c2 = paramGet(app, 'contour_2');
            c_bottom = paramGet(app, 'contour_3');
            left_corner = paramGet(app, 'top_left_corner');
            right_corner = paramGet(app, 'top_right_corner');
            
            left_corner = paramGet(app, 'top_left_corner');
            right_corner = paramGet(app, 'top_right_corner');
                        
            % Set limits
            y_top_limit = min(left_corner(2), right_corner(2));
            [img_height, img_width, ~] = size(app.D);
            min_x = max(min(c_bottom(:, 1)), min(c2(:, 1)));
            max_x = min(max(c_bottom(:, 1)), max(c2(:, 1)));
            
            [x_grid, y_grid] = meshgrid(1:img_width, 1:img_height);
            y_top_interp = interp1(c2(:, 1), c2(:, 2), min_x:max_x, 'linear', 'extrap');
            y_bottom_interp = interp1(c_bottom(:, 1), c_bottom(:, 2), min_x:max_x, 'linear', 'extrap');
            x_mask = (x_grid >= min_x) & (x_grid <= max_x);
            
            % Extract tissue
            tissue_mask = false(img_height, img_width);  
            tissue_mask(:, min_x:max_x) = (y_grid(:, min_x:max_x) < y_top_interp) & ...
                                          (y_grid(:, min_x:max_x) >= y_top_limit) & ...
                                          x_mask(:, min_x:max_x);
            tissue_img = app.D;
            tissue_img(repmat(~tissue_mask, [1, 1, size(app.D, 3)])) = 0;
            
            % Extract cartilage
            cart_mask = false(img_height, img_width);  % Initialize full mask
            cart_mask(:, min_x:max_x) = (y_grid(:, min_x:max_x) >= y_top_interp) & ...
                                           (y_grid(:, min_x:max_x) <= y_bottom_interp) & ...
                                           x_mask(:, min_x:max_x);
                                       
            cart_img = app.D;
            cart_img(repmat(~cart_mask, [1, 1, size(app.D, 3)])) = 0;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cla(app.axes,'reset')
            disableDefaultInteractivity(app.axes);
            app.axes.Visible = 'off';
            app.axes.Toolbar = [];
            app.btnCompile.Visible = 'off';
            app.btnZoom.Visible = 'off';
            app.btnPan.Visible = 'off';
            app.btnHome.Visible = 'off';
            app.panSettings.Visible = 'off';
            app.panMeasureOpt.Visible = 'off';
            app.panResults.Visible = 'off';
            app.panSave.Visible = 'off';
            app.panEnhance.Visible = 'off';
            app.panROI.Visible = 'off';
        end

        % Button pushed function: btnSave
        function btnSaveButtonPushed(app, event)
            tracing_state(app, 'off')
            if (app.ddProcess.Value == 1)
                btnMeasureButtonPushed(app)
                process_single_file(app, app.params.path)
            else
                % save final settings to over write all other image
                % settings that were saved per file
                number_contours = app.ddNumContours.Value;
                inter_pct = app.txtInterROIPct.Value;
                num_subrois = app.txtNumROI.Value;
                ml_option = app.ddLengthOpt.Value;
                sos_correction = app.txtSpeed.Value;
                quest = {'Processing all files with following settings: ', ...
                    ['Contours = ', num2str(app.ddNumContours.Value)], ...
                    ['Intercondylar Notch Percentage = ', num2str(app.txtInterROIPct.Value)], ...
                    ['Number of Sub-ROIs = ', num2str(app.txtNumROI.Value)], ...
                    ['M/L Length Option = ', app.ddLengthOpt.Value], ...
                    ['Speed of Sound Correction = ', num2str(app.txtSpeed.Value)]};
                opts.Interpreter = 'tex';
                opts.Default = 'Continue';
                answer = questdlg(quest, 'Check the following Settings', ...
                    'Continue','Cancel',opts);
                if (strcmp(answer, 'Cancel'))
                    tracing_state(app, 'on')
                    return
                end

                mat_files = dir(fullfile(app.txtStudyFolder.Value,'\*\*.mat'));

                for k = 1:size(mat_files,1)
                    % File path
                    matfile_path = fullfile(mat_files(k).folder, mat_files(k).name);

                    % Are previously processed files potentially excluded?
                    if (app.ddProcess.Value == 3) || (app.ddProcess.Value == 4)
                        % Store the current the processing parameters
                        % in structure
                        pc = [];
                        pc.processed = false;
                        pc.num_of_contours = app.ddNumContours.Value;
                        pc.intercondylar_percentage = app.txtInterROIPct.Value;
                        pc.number_ROIs = app.txtNumROI.Value;
                        pc.ML_length = app.ddLengthOpt.Value;
                        pc.speed_correction_factor = app.txtSpeed.Value;

                        % Yes, check the previous processing parameters
                        p = paramGet(app, 'processing_params', pc, matfile_path);

                        % Has the file been previously processed?
                        if (p.processed)
                            % Yes

                            % Are we excluding all previously
                            % processed files?
                            if (app.ddProcess.Value == 3)
                                % Yes, therefore exclude this file
                                continue
                            end

                            % Are the parameters for the previous
                            % processing identical to the current ones?
                            if (strcmp(p.num_of_contours, pc.num_of_contours) && ...
                                    (p.intercondylar_percentage == pc.intercondylar_percentage) && ...
                                    (p.number_ROIs == pc.number_ROIs) && ...
                                    strcmp(p.ML_length,pc.ML_length) && ...
                                    (p.speed_correction_factor == pc.speed_correction_factor))
                                % Yes, exclude this file
                                continue
                            end
                        end
                    end

                    % Check that the required contours are present
                    if (isempty(paramGet(app, 'contour_2', [], matfile_path))), continue, end
                    if (isempty(paramGet(app, 'contour_3', [], matfile_path))), continue, end
                    if (strcmp(app.ddNumContours.Value, 'Three') && ...
                            isempty(paramGet(app, 'contour_1', [], matfile_path))), continue, end

                    file_split = split(string(matfile_path),'\');

                    %subject
                    t = find(strcmp(app.ddSubs.Items, file_split(end-1)));
                    app.ddSubs.Value = t;
                    ddSubsValueChanged(app, event);

                    %image
                    im = file_split(end);
                    [~,name,~] = fileparts(im);
                    im_index = find(startsWith(app.ddImages.Items, name));
                    app.ddImages.Value = im_index;
                    ddImagesValueChanged(app, event)

                    % Update settings to keep consistent
                    app.ddNumContours.Value = number_contours;
                    app.txtInterROIPct.Value = inter_pct;
                    app.txtNumROI.Value = num_subrois;
                    app.ddLengthOpt.Value = ml_option;
                    app.txtSpeed.Value = sos_correction;

                    % Measure
                    btnMeasureButtonPushed(app)
                    process_single_file(app, matfile_path)
                end
            end

            app.txtFeedback.Text = 'Saved!';
            pause(1)
            app.txtFeedback.Text = '';
            tracing_state(app, 'on')
        end

        % Value changed function: btnAccept
        function btnAcceptValueChanged(app, event)
            tracing_state(app, 'off')
            app.btnAccept.Visible = 'off';
            if strcmp(app.btnAccept.Text, 'Accept Corners')
                app.txtFeedback.Text = 'Reposition corners and then click accept';
                drawnow
                app.pos_corner_left = app.roi_corner_left.Position;
                app.pos_corner_right = app.roi_corner_right.Position;

                paramSave(app, 'top_left_corner', [app.pos_corner_left(1) app.pos_corner_left(2)]);
                paramSave(app, 'top_right_corner', [app.pos_corner_right(1) app.pos_corner_right(2)]);

                delete(app.roi_corner_left);
                delete(app.roi_corner_right);

                if isempty(app.listMeasures.Items)
                    app.listMeasures.Items = {'Image Corners'};
                end
                app.listMeasures.Value = {'Image Corners'};

            elseif strcmp(app.btnAccept.Text, 'Accept Notch')
                app.txtFeedback.Text = 'Reposition intercondylar notch and then click accept';
                drawnow
                app.pos_notch = app.roi_notch.Position;

                app.listMeasures.Items = {'Intercondylar Notch','Contours'};
                app.listMeasures.Value = {'Intercondylar Notch','Contours'};
                paramSave(app, 'intercondylar_notch', [app.pos_notch(1) app.pos_notch(2)]);

                delete(app.roi_notch);

            elseif strcmp(app.btnAccept.Text, 'Accept & Save Contours')
                switch app.ddNumContours.Value
                    case 'Two'
                        app.pos_1 = [];
                    case 'Three'
                        app.pos_1 = app.roi_1.Position;
                        paramSave(app, 'pos_1', [app.pos_1(:,1) app.pos_1(:,2)]);
                end
                app.pos_2 = app.roi_2.Position;
                app.pos_3 = app.roi_3.Position;
                paramSave(app, 'pos_2', [app.pos_2(:,1) app.pos_2(:,2)]);
                paramSave(app, 'pos_3', [app.pos_3(:,1) app.pos_3(:,2)]);

                accept_contours(app, app.roi_1, app.roi_2, app.roi_3, app.pos_1, app.pos_2, app.pos_3);
            end

            refreshAxes(app,app.axes)
            tracing_state(app,'on')
            app.txtFeedback.Text = '';
            drawnow
            app.btnAccept.Value = 0;
        end

        % Callback function
        function btnAddButtonPushed(app, event)
            c1 = paramGet(app, 'contour_1', double.empty(0, 2));
            c2 = paramGet(app, 'contour_2', double.empty(0, 2));
            c3 = paramGet(app, 'contour_3', double.empty(0, 2));

            if isempty(c2) || isempty(c3)
                disp('ERROR: Need to draw atleast 2 contours first.')
                return
            end

            if isempty(c1)
                app.txtFeedback.Text = 'Trace the near quadriceps tendon-cartilage contour';
                app.roi_1 = images.roi.Polyline(app.axes,'LineWidth',1,'Color','cyan', 'MarkerSize', 5);
                draw(app.roi_1)
                app.ddNumContours.Value = 'Three';
            end

            app.btnAccept.Text = 'Accept New Contour'; % new
            app.btnAccept.Visible = 'on';

            paramSave(app, 'num_of_contours', app.ddNumContours.Value)
        end

        % Button pushed function: btnAdjustContours
        function btnAdjustContoursButtonPushed(app, event)
            app.pos_1 = paramGet(app, 'pos_1', double.empty(0, 2));
            app.pos_2 = paramGet(app, 'pos_2', double.empty(0, 2));
            app.pos_3 = paramGet(app, 'pos_3', double.empty(0, 2));

            if isempty(app.pos_2) || isempty(app.pos_3)
                errordlg('Need to draw the contours before you can adjust them :)','Error');
                return
            end
            tracing_state(app,'off')
            clear_measures(app)
            app.txtFeedback.Text = 'Adjust contours then click "Accept"';
            btnMeasuresNoneButtonPushed(app, event);
            app.roi_2 = drawpolyline(app.axes, 'Position',[app.pos_2(:,1) app.pos_2(:,2)], ...
                'LineWidth',1,'Color','cyan', 'MarkerSize', 5);
            app.roi_3 = drawpolyline(app.axes, 'Position',[app.pos_3(:,1) app.pos_3(:,2)], ...
                'LineWidth',1,'Color','red', 'MarkerSize', 5);

            if strcmp(app.ddNumContours.Value, 'Three') % 3 contours
                if isempty(app.pos_1)
                    errordlg('Need to draw the contours before you can adjust them :)','Error');
                    tracing_state(app, 'on')
                    return
                else
                    app.roi_1 = drawpolyline(app.axes, 'Position',[app.pos_1(:,1) app.pos_1(:,2)], ...
                        'LineWidth',1,'Color','cyan', 'MarkerSize', 5);
                end
            end

            app.btnAccept.Text = 'Accept & Save Contours'; % new
            app.btnAccept.Visible = 'on';
        end

        % Button pushed function: btnAdjustNotch
        function btnAdjustNotchPushed(app, event)
            tracing_state(app,'off')
            clear_measures(app, false)
            app.listMeasures.Value = 'Contours';
            listMeasuresValueChanged(app)

            contour_3 = paramGet(app, 'contour_3', double.empty(0, 2));
            %             contour_2 = paramGet(app, 'contour_2', double.empty(0, 2));
            if isempty(contour_3)
                errordlg('Need to draw the contours first','Error');
                tracing_state(app, 'on')
                return
            end
            constrained_rect_pos = [min(contour_3(:,1)), min(contour_3(:,2)), numel(contour_3(:,1)), (max(contour_3(:,2))-min(contour_3(:,2)))];
            notch = paramGet(app, 'intercondylar_notch', double.empty(0, 2));
            app.txtFeedback.Text = 'Adjust intercondylar notch ROI then click "Accept"';

            app.roi_notch = images.roi.Point(app.axes,'Position',[notch(1) notch(2)],'Color','cyan', 'MarkerSize', 5, ...
                'DrawingArea',constrained_rect_pos);
            drawnow

            app.btnAccept.Text = 'Accept Notch'; % new
            app.btnAccept.Visible = 'on';
        end

        % Button pushed function: btnChooseStudy
        function btnChooseStudyButtonPushed(app, event)
            newPath = uigetdir(app.txtStudyFolder.Value, 'Pick a study folder');
            figure(app.UIFigure);
            if (newPath ~= 0)
                app.txtStudyFolder.Value = newPath;
                txtStudyFolderValueChanged(app, event)
            end
        end

        % Button pushed function: btnColors
        function btnColorsButtonPushed(app, event)
            m = app.listMeasures.Value;
            switch string(m)
                case 'Contours'
                    color = uicolorpicker;
                    figure(app.UIFigure);
                    if ~isempty(color)
                        paramSave(app, 'contour_color', color);
                        app.btnColors.BackgroundColor = color;
                    end
                case 'Intercondylar Notch'
                    color = uicolorpicker;
                    figure(app.UIFigure);
                    if ~isempty(color)
                        paramSave(app, 'notch_color', color)
                        app.btnColors.BackgroundColor = color;
                    end
                case 'Image Corners'
                    color = uicolorpicker;
                    figure(app.UIFigure);
                    if ~isempty(color)
                        paramSave(app, 'corner_color', color);
                        app.btnColors.BackgroundColor = color;
                    end
                case 'Regions'
                    color = uicolorpick3;
                    figure(app.UIFigure);
                    if ~isempty(color)
                        paramSave(app, 'region_color', color);
                        app.btnColors.BackgroundColor = color{1};
                    end % use default colors
            end

            refreshAxes(app);
        end

        % Button pushed function: btnCompile
        function btnCompilePushed(app, event)
            app.txtFeedback.Text = 'Compiling data from all subjects...';
            drawnow
            [~, study_name]= fileparts(app.txtStudyFolder.Value);
            mat_files = dir(fullfile(app.txtStudyFolder.Value,'\*\*.mat'));
            T_all = table();
            T_avg = table();
            T_subject_all = table();
            T_subject_avg = table();

            for k = 1:size(mat_files,1)
                % Load output data and check if empty
                LD = paramGet(app, 'limb_output_data', [], fullfile(mat_files(k).folder, mat_files(k).name));
                if (isempty(LD)), continue, end

                % Remove the template
                LD = rmfield(LD, 'template');

                % Subject info
                [~, T_subject_all.SubjectID,~] = fileparts(mat_files(k).folder);
                T_subject_all.Limb = paramGet(app, 'limb', '', fullfile(mat_files(k).folder, mat_files(k).name));
                T_subject_all.Filename = string(mat_files(k).name);
                T_subject_avg.SubjectID = T_subject_all.SubjectID;
                T_subject_avg.Limb = T_subject_all.Limb;

                % Transform to table
                T_data = struct2tableAll(LD);
                T_data_all = [T_subject_all T_data];
                T_data_avg = [T_subject_avg T_data];

                % Append
                T_all = [T_all ; T_data_all]; %#ok<AGROW>
                T_avg = [T_avg ; T_data_avg]; %#ok<AGROW>
            end

            % Save All Files Individually
            T_all_filename = [app.txtStudyFolder.Value, '\', study_name, '_Compiled_Output.csv'];
            try
                writetable(T_all, T_all_filename);
            catch
                app.txtFeedback.Text = '';
                errordlg('Compiled file is open. Close file and try again','Error');
            end

            % Average by Limb
            T_avg.SubjectID = categorical(cellstr(T_avg.SubjectID));
            T_avg.Limb = categorical(cellstr(T_avg.Limb));

            group_cols = T_avg(:, 1:(find(contains(T_avg.Properties.VariableNames, 'whole'),1)-1));

            avg_out = grpstats(T_avg, group_cols.Properties.VariableNames);
            avg_out.Properties.VariableNames = strrep(avg_out.Properties.VariableNames, 'mean_', '');
            avg_out.Properties.VariableNames{3} = 'NumofFiles';
            avg_out.Properties.RowNames ={};
            T_avg_filename = [app.txtStudyFolder.Value, '\', study_name, '_AverageByLimb_Compiled_Output.csv'];

            try
                writetable(avg_out, T_avg_filename);
            catch
                app.txtFeedback.Text = '';
                errordlg('Compiled file is open. Close file and try again','Error');
            end

            app.txtFeedback.FontSize = 10;
            app.txtFeedback.Text = sprintf('Saved! Find compiled datasets at:\n %s \n %s', ...
                T_all_filename, T_avg_filename);

            pause(3)
            app.txtFeedback.Text = '';
            app.txtFeedback.FontSize = 18;
        end

        % Button pushed function: btnCorners
        function btnCornersPushed(app, event)
            tracing_state(app, 'off')
            app.chkViewFull.Value = 1;
            btnMeasuresNoneButtonPushed(app, event);

            left_corner = paramGet(app, 'top_left_corner', double.empty(0, 2));
            right_corner = paramGet(app, 'top_right_corner', double.empty(0, 2));
            app.txtFeedback.Text = 'Adjust corners then click "Accept"';

            if ~(size(left_corner, 1) == 1 && size(left_corner, 2) == 2)
                left_corner = [app.axes.XLim(2)*.05, app.axes.YLim(2)*.05];
                right_corner = [app.axes.XLim(2)*.95, app.axes.YLim(2)*.05];
            end

            app.roi_corner_left = images.roi.Point(app.axes,'Position',[left_corner(1) left_corner(2)],'Color','r', 'MarkerSize', 5);
            app.roi_corner_right = images.roi.Point(app.axes,'Position',[right_corner(1) right_corner(2)],'Color','r', 'MarkerSize', 5);
            addlistener(app.roi_corner_left,'MovingROI',@(src,evt) show_label(app, src,evt));
            addlistener(app.roi_corner_right,'MovingROI',@(src,evt) show_label(app, src,evt));
            drawnow

            app.btnAccept.Text = 'Accept Corners'; % new
            app.btnAccept.Visible = 'on';
        end

        % Button pushed function: btnCrop
        function btnCropButtonPushed(app, event)
            % Reset to full view and suppress measures
            tracing_state(app, 'off')
            app.txtFeedback.Text = 'Select cropped region';
            app.chkViewFull.Value = 0;
            paramSave(app, 'crop', [1 size(app.D, 2) 1 size(app.D, 1)])
            refreshAxes(app, [], false);

            % Trace new crop
            roi = images.roi.Rectangle(app.axes);
            draw(roi)
            pos = round(roi.Position);
            delete(roi)
            app.txtFeedback.Text = '';

            % Top
            top = min(max(pos(2), 1), size(app.D, 1));

            % Left
            left = min(max(pos(1), 1), size(app.D, 2));

            % Bottom = top + height
            bottom = top + pos(4);
            bottom = min(max(bottom, top + 1), size(app.D, 1));

            % Right = left + width
            right = left + pos(3);
            right = min(max(right, left + 1), size(app.D, 2));

            % Apply new crop
            paramSave(app, 'crop', [left right top bottom]);

            % Refresh view
            refreshAxes(app)
            tracing_state(app, 'on', true)
        end

        % Button pushed function: btnDraw
        function btnDrawButtonPushed(app, event)
            tracing_state(app, 'off')
            clear_measures(app)
            btnMeasuresNoneButtonPushed(app, event);

            if ~strcmp(app.ddNumContours.Value,'Two')
                app.txtFeedback.Text = 'Trace the near quadriceps tendon-cartilage contour';
                app.roi_1 = images.roi.Polyline(app.axes,'LineWidth',1,'Color','cyan', 'MarkerSize', 5);
                draw(app.roi_1)
            else
                app.roi_1 = [];
            end

            app.txtFeedback.Text = 'Trace the far quadriceps tendon-cartilage contour';
            app.roi_2 = images.roi.Polyline(app.axes,'LineWidth',1,'Color','cyan', 'MarkerSize', 5);
            draw(app.roi_2)

            app.txtFeedback.Text = 'Trace the near cartilage-bone contour';
            app.roi_3 = images.roi.Polyline(app.axes,'LineWidth',1,'Color','red','MarkerSize', 5);
            draw(app.roi_3)

            app.btnAccept.Text = 'Accept & Save Contours'; % new
            app.btnAccept.Visible = 'on';

            paramSave(app, 'num_of_contours', app.ddNumContours.Value)
        end

        % Button pushed function: btnLoad
        function btnLoadButtonPushed(app, event)
            if (isempty(app.ddImages.Value)), return, end

            % Get the subject folder
            subject = string(app.ddSubs.Items(app.ddSubs.Value));
            subject_folder = fullfile(app.txtStudyFolder.Value,subject);

            % Get the currently selected image
            file_name = string(app.ddImages.Items(app.ddImages.Value));

            % Load dicom that's selected
            selected_image = fullfile(subject_folder, file_name);
            if endsWith(selected_image,'.dcm')
                info = dicominfo(selected_image);
                app.D = dicomread(info);
            elseif endsWith(selected_image,'.jpg')
                try
                    app.D = imread(selected_image);
                catch
                    errordlg('Image not found. Be sure to select study folder.');
                    return
                end
            elseif endsWith(selected_image,'.png')
                try
                    app.D = imread(selected_image);
                catch
                    errordlg('Image not found. Be sure to select study folder.');
                    return
                end
            else
                errordlg('Image not found. Be sure to select study folder.');
                return
            end

            app.D_original = app.D;
            app.D_grayscale = 0.2989 * app.D_original(:, :, 1, :) + 0.5870 * app.D_original(:, :, 2, :) + 0.1140 * app.D_original(:, :, 3, :);

            % Parameters file
            [~,file_name_no_ext,~] = fileparts(selected_image);
            app.params.path = fullfile(subject_folder, strcat(file_name_no_ext, '.mat'));

            % Get info from csv file for loaded image and save to params
            master_struct = dir(fullfile(app.txtStudyFolder.Value,'*_StudyInfo*.csv'));
            master = readcell(fullfile(master_struct.folder,master_struct.name));
            sub_index = ismember(master(:,1),cellstr(subject));
            sub_images = master(sub_index,:);
            image_index = find(ismember(sub_images(:,2),cellstr(file_name)));
            if isempty(image_index)
                app.txtFeedback.Text = '';
                errordlg(['Image info not found in ' master_struct.name '. Add filename and image information to csv file.']);
                return
            else
                app.txtFeedback.Text = 'Loading...';
                drawnow
            end
 
            if iscell(sub_images(image_index,5))
                resx = cell2mat(sub_images(image_index,ismember(master(1,:),'Resolution X')));
                resy = cell2mat(sub_images(image_index,ismember(master(1,:),'Resolution Y')));
            elseif isstring(sub_images(image_index,5))
                resx = str2double(sub_images(image_index,ismember(master(1,:),'Resolution X')));
                resy = str2double(sub_images(image_index,ismember(master(1,:),'Resolution Y')));
            end

            limb = char(sub_images(image_index,ismember(master(1,:),'Limb')));
            medial_side = char(sub_images(image_index,contains(master(1,:),'Medial')));

            paramSave(app, 'limb', limb)
            paramSave(app, 'medial_side', medial_side)
            paramSave(app, 'resolutionx', resx)
            paramSave(app, 'resolutiony', resy)
            
            % Set image type 
            image_type = paramGet(app, 'Image_type');
            if ~isempty(image_type)
                app.ddImageType.Value = image_type;
            end
            
            % Display items
            regions = paramGet(app, 'regions');
            contour_2 = paramGet(app, 'contour_2');
            inter_notch = paramGet(app, 'intercondylar_notch',double.empty(0, 2));
            top_left_corner = paramGet(app, 'top_left_corner', double.empty(0, 2));
            top_right_corner = paramGet(app, 'top_right_corner', double.empty(0, 2));
            if (isempty(inter_notch) && isempty(top_left_corner) && isempty(top_right_corner))
                app.listMeasures.Items = {};
            else
                if ~isempty(regions)
                    app.listMeasures.Items = {'Contours','Regions','Intercondylar Notch','Image Corners'};
                    app.listMeasures.Value = {'Contours','Intercondylar Notch'};
                elseif ~isempty(contour_2) && ~isempty(top_left_corner) && ~isempty(top_right_corner)
                    app.listMeasures.Items = {'Contours','Intercondylar Notch','Image Corners'};
                    app.listMeasures.Value = {'Contours','Intercondylar Notch','Image Corners'};
                elseif isempty(contour_2) && ~isempty(top_left_corner) && ~isempty(top_right_corner)
                    app.listMeasures.Items = {'Image Corners'};
                    app.listMeasures.Value = {'Image Corners'};
                else
                    app.listMeasures.Items = {};
                end
            end

            % Set Contour Colors
            contour_color = paramGet(app, 'contour_color', 'y');
            app.btnColors.BackgroundColor = contour_color;

            % find image corners for tissue measurements
            if  (isempty(top_left_corner) || isempty(top_right_corner) || (size(top_left_corner, 2) ~= 2) || (size(top_right_corner, 2) ~= 2))
                find_top_image_corners(app)
            end

            if (~isempty(regions)), app.txtNumROI.Value = regions.ns; end

            app.txtInterROIPct.Value = paramGet(app, 'intercondylar_percentage', 25);
            app.ddNumContours.Value = paramGet(app, 'num_of_contours', 'Two');
            app.ddLengthOpt.Value = paramGet(app, 'ML_length', 'Minimum');

            refreshAxes(app);

            app.btnCompile.Visible = 'on';
            app.btnZoom.Visible = 'on';
            app.btnPan.Visible = 'on';
            app.btnHome.Visible = 'on';
            app.panSettings.Visible = 'on';
            app.panResults.Visible = 'on';
            app.panMeasureOpt.Visible = 'on';
            app.panSave.Visible = 'on';
            app.panEnhance.Visible = 'on';
            app.panROI.Visible = 'on';

            app.txtFeedback.Text = '';
            drawnow
        end

        % Value changed function: btnHelpMLlength
        function btnHelpMLlengthValueChanged(app, event)
            value = app.btnHelpMLlength.Value;
            if value == 1
                app.txtHelpMLlength.Enable = 'on';
                app.txtHelpMLlength.Visible = 'on';
            else
                app.txtHelpMLlength.Enable = 'off';
                app.txtHelpMLlength.Visible = 'off';
            end
        end

        % Button pushed function: btnMeasure
        function btnMeasureButtonPushed(app, event)
            if isempty(paramGet(app, 'contour_2', double.empty(0, 2)))
                errordlg('Need to draw contours first','Error: Draw Contours');
                return
            end

            app.txtFeedback.Text = 'Measuring...';
            drawnow

            % Number of regions and subregions
            nr = 3;
            ns = app.txtNumROI.Value;

            % Number of contours
            num_contours = app.ddNumContours.Value;
            if strcmp(num_contours, 'Two')
                loop = 2:3;
            elseif strcmp(num_contours, 'Three')
                loop = 1:3;
            end

            % Medial location
            medial_left = strcmp(paramGet(app, 'medial_side', 'L'), 'L');
            
            % Length option
            if (strcmp(app.ddLengthOpt.Value, '10 mm'))
                length_mm = 10;
            else
                length_mm = [];
            end

            % Initialize region and subregion arrays
            r = zeros(size(app.D, 2), 1);
            s = zeros(size(r));

            % Find the common range of x for the contours selected
            x_left = 1;
            x_right = size(app.D, 2);
            nc = 0;
            for c = loop
                c_pts = paramGet(app, ['contour_' num2str(c)]);
                if (isempty(c_pts)), continue, end

                nc = nc + 1;
                x_left = max(x_left, min(c_pts(:, 1)));
                x_right = min(x_right, max(c_pts(:, 1)));
            end
            if (nc < 1), return, end

            % Get the notch location
            notch = paramGet(app, 'intercondylar_notch');
            if (isempty(notch)), return; end
            x_notch = round(notch(1));

            % Center about the notch
            n_half = min(x_right - x_notch, x_notch - x_left);
            use_distance = false;
            if (~isempty(length_mm))
                x_res = paramGet(app, 'resolutionx');
                length_px = length_mm / x_res;
                length_px_mid = (app.txtInterROIPct.Value / 100) * length_px * 2;
                n_half_dist = round(length_px + (length_px_mid / 2));
                use_distance = (n_half_dist <= n_half);
            end

            % Get the half-length of the mid region (excludes notch)
            if (use_distance)
                x_right = x_notch + n_half_dist;
                x_left = x_notch - n_half_dist;
                n_mid_half = round(length_px_mid / 2);
            else
                x_right = x_notch + n_half;
                x_left = x_notch - n_half;
                n_mid_half = floor((app.txtInterROIPct.Value / 100) * (x_right - x_left) / 2);
            end

            % Get the x range for each region
            x = cell(nr, 1);
            x{1} = x_left:(x_notch - n_mid_half - 1);
            x{2} = (x_notch - n_mid_half):(x_notch + n_mid_half);
            x{3} = (x_notch + n_mid_half + 1):x_right;

            % Split into subregions
            for kr = 1:nr
                % Assign region numbers
                xr = x{kr};
                if (medial_left)
                    r(xr) = kr;
                else
                    r(xr) = nr - kr + 1;
                end

                % Number of points per subregion
                num_pts_sub = floor(numel(xr) / ns);

                % Number of leftover points
                num_pts_rem = numel(xr) - (num_pts_sub * ns);

                % Assign subregion indices
                if (num_pts_sub < 1), continue, end

                x_left = xr(1);
                x_right = x_left + num_pts_sub - 1;
                for ks = 1:ns
                    % Assign points
                    s(x_left:x_right) = ks;
                    x_left = x_left + num_pts_sub;
                    x_right = x_right + num_pts_sub;

                    % Assign remainder, if any
                    if (num_pts_rem > 0)
                        s(x_left) = ks;
                        num_pts_rem = num_pts_rem - 1;
                        x_left = x_left + 1;
                        x_right = x_right + 1;
                    end
                end
            end

            % Save regions structure so far
            regions = [];
            regions.r = r;
            regions.s = s;
            regions.nr = nr;
            regions.ns = ns;
            regions.patch = cell(3, ns, 2);

            % Patch boundaries
            c1 = paramGet(app, 'contour_1');
            c2 = paramGet(app, 'contour_2');
            c_bottom = paramGet(app, 'contour_3');

            for nc = 1:2
                if (nc == 1)
                    c_top = c1;
                else
                    c_top = c2;
                end
                if (isempty(c_top)), continue, end

                % Move forward along top
                for kr = 1:3
                    for ks = 1:ns
                        % Find the range of x for this area
                        x_range = find((r == kr) & (s == ks));
                        if (isempty(x_range)), continue, end
                        x_min = min(x_range);
                        x_max = max(x_range);

                        % Extract contour portions
                        x_ind_top = (c_top(:, 1) >= x_min) & (c_top(:, 1) <= x_max);
                        x_ind_bottom = (c_bottom(:, 1) >= x_min) & (c_bottom(:, 1) <= x_max);

                        % Form the contour of the patch
                        regions.patch{kr, ks, nc} = [c_top(x_ind_top, :) ; flipud(c_bottom(x_ind_bottom, :))];
                    end
                end

            end

            paramSave(app, 'regions', regions)

            % get trochlear measures
            if strcmp(app.ddImageType.Value, 'Transverse')
                [sulcus_angle, sulcus_depth] = femoral_trochlear(app, c_bottom);
            else
                sulcus_angle = [];
                sulcus_depth = [];
            end

            % Get tissue and cartilage masks
            if strcmp(num_contours, 'Two')
                [tissue_img, cart_img] = get_image_masks(app);
                paramSave(app, 'main_img', app.D)
                paramSave(app, 'tissue_img', tissue_img)
                paramSave(app, 'cart_img', cart_img)
            end

            % Update Measures and save 
            app.listMeasures.Items = {'Contours','Regions','Intercondylar Notch','Image Corners'};
            paramSave(app, 'trochlear_output', [sulcus_angle, sulcus_depth]);
            paramSave(app, 'intercondylar_notch', notch)
            paramSave(app, 'intercondylar_percentage', app.txtInterROIPct.Value);
            paramSave(app, 'speed_correction_factor', app.txtSpeed.Value)
            paramSave(app, 'ML_length', app.ddLengthOpt.Value)
            

            app.listMeasures.Value = {'Regions'};
            refreshAxes(app,app.axes)
            app.txtFeedback.Text = '';
        end

        % Button pushed function: btnMeasuresAll
        function btnMeasuresAllButtonPushed(app, event)
            app.listMeasures.Value = app.listMeasures.Items;
            listMeasuresValueChanged(app, event)
        end

        % Button pushed function: btnMeasuresNone
        function btnMeasuresNoneButtonPushed(app, event)
            app.listMeasures.Value = {};
            listMeasuresValueChanged(app, event)
        end

        % Value changed function: btnPan
        function btnPanValueChanged(app, event)
            if app.btnPan.Value == 1
                reset_image_buttons(app, 'pan', 1)
            else
                reset_image_buttons(app, 'pan', 0)
            end
        end

        % Value changed function: btnHome
        function btnHomeValueChanged(app, event)
            if app.btnHome.Value == 1
                reset_image_buttons(app, 'home', 1)
            else
                reset_image_buttons(app, 'home', 0)
            end
        end

        % Value changed function: btnHelpSpeed
        function btnHelpSpeedValueChanged(app, event)
            value = app.btnHelpSpeed.Value;
            if value == 1
                app.txtHelpSpeed.Enable = 'on';
                app.txtHelpSpeed.Visible = 'on';
            else
                app.txtHelpSpeed.Enable = 'off';
                app.txtHelpSpeed.Visible = 'off';
            end
        end

        % Value changed function: btnZoom
        function btnZoomValueChanged(app, event)
            if app.btnZoom.Value == 1
                reset_image_buttons(app, 'zoom', 1)
            else
                reset_image_buttons(app, 'zoom', 0)
            end
        end

        % Value changed function: btnHelpCorners
        function btnHelpCornersValueChanged(app, event)
            value = app.btnHelpCorners.Value;
            if value == 1
                app.txtHelpCorners.Enable = 'on';
                app.txtHelpCorners.Visible = 'on';
            else
                app.txtHelpCorners.Enable = 'off';
                app.txtHelpCorners.Visible = 'off';
            end
        end

        % Value changed function: btnHelpImage
        function btnHelpImageValueChanged(app, event)
            value = app.btnHelpImage.Value;
            if value == 1
                app.txtHelpImage.Visible = 'on';
            else
                app.txtHelpImage.Visible = 'off';
            end
        end

        % Value changed function: chkAutoLoad
        function chkAutoLoadValueChanged(app, event)
            if (app.chkAutoLoad.Value == 1)
                app.btnLoad.Enable = 'off';
            else
                app.btnLoad.Enable = 'on';
            end
        end

        % Value changed function: chkViewFull
        function chkViewFullValueChanged(app, event)
            refreshAxes(app);
        end

        % Value changed function: chkViewOriginal
        function chkViewOriginalValueChanged(app, event)
            app.ddFilters.Value = 'None';
            if app.chkViewOriginal.Value == true
                app.sldContrast.Value = 50;
            else
                app.sldContrast.Value = app.sldContrast.Value;
            end
            refreshAxes(app)
            app.chkViewOriginal.Value = 0;
        end

        % Value changed function: ddFilters
        function ddFiltersValueChanged(app, event)
            app.txtFeedback.Text = 'Filtering Image...';
            drawnow
            refreshAxes(app)
        end

        % Value changed function: ddSubs
        function ddSubsValueChanged(app, event)
            [subject_folder, ~] = getSubject(app);
            all = dir(fullfile(subject_folder));
            no_ext = all(~contains({all.name},{'.'}));
            d = dir(fullfile(subject_folder,'*.dcm'));
            j = dir(fullfile(subject_folder,'*.jpg'));
            p = dir(fullfile(subject_folder,'*.png'));
            s = [d; j; p; no_ext;];
            num_images = length(s);

            % Check if no images
            if (num_images < 1)
                app.ddImages.Items = {};
                app.ddImages.ItemsData = [];
                errordlg(['No dcm or jpg files found in "' subject_folder '".'], 'No file');
                return
            end

            % Get list of images
            for k = 1:num_images; images{k} = s(k).name; end %#ok<AGROW>

            % Populate dropdown
            app.ddImages.Items = images;
            app.ddImages.ItemsData = 1:num_images;
            app.ddImages.Value = 1;

            % Auto-load if enabled
            if (app.chkAutoLoad.Value == 1); btnLoadButtonPushed(app, event); end
        end

        % Value changed function: ddImages
        function ddImagesValueChanged(app, event)
            if (app.chkAutoLoad.Value == 1); btnLoadButtonPushed(app, event); end
        end

        % Value changed function: listMeasures
        function listMeasuresValueChanged(app, event)
            if size(app.listMeasures.Value,1) == 1
                switch string(app.listMeasures.Value)
                    case 'Image Corners'
                        corner_color = paramGet(app, 'corner_color', double.empty(0, 2));
                        app.btnColors.BackgroundColor = corner_color;
                    case 'Regions'
                        region_color = paramGet(app, 'region_color', double.empty(0, 2));
                        app.btnColors.BackgroundColor = region_color{1};
                    case 'Contours'
                        contour_color = paramGet(app, 'contour_color', double.empty(0, 2));
                        app.btnColors.BackgroundColor = contour_color;
                    case 'Intercondylar Notch'
                        notch_color = paramGet(app, 'notch_color', double.empty(0, 2));
                        app.btnColors.BackgroundColor = notch_color;
                end
            end

            if (any(ismember(app.listMeasures.Value, 'Image Corners')))
                app.chkViewFull.Value = 1;
            else
                app.chkViewFull.Value = app.chkViewFull.Value;
            end

            refreshAxes(app);
        end

        % Value changed function: sldContrast
        function sldContrastValueChanged(app, event)
            app.chkViewOriginal.Value = false;
            refreshAxes(app);
        end

        % Value changed function: txtStudyFolder
        function txtStudyFolderValueChanged(app, event)
            subject_folder_struct = dir(app.txtStudyFolder.Value);
            subject_folders = subject_folder_struct(~contains({subject_folder_struct.name},{'.'}));

            num_subs = length(subject_folders);

            %Check if no subjects
            if (num_subs < 1)
                app.ddSubs.Items = {};
                app.ddSubs.ItemsData = [];
                errordlg('Please select study folder containing subject folders. Be sure not to select an individual subject folder.','No subjects found' );
                return
            end

            % Get list of subjects
            for k = 1:num_subs
                subs{k} = subject_folders(k).name; %#ok<AGROW>
            end

            % Populate dropdown
            app.ddSubs.Items = subs;
            app.ddSubs.ItemsData = 1:num_subs;
            app.ddSubs.Value = 1;

            % Call next image drop down
            ddSubsValueChanged(app, event)
        end

        % Value changed function: btnHelpCompile
        function btnHelpCompileValueChanged(app, event)
            value = app.btnHelpCompile.Value;
            if value == 1
                app.txtHelpCompile.Enable = 'on';
                app.txtHelpCompile.Visible = 'on';
            else
                app.txtHelpCompile.Enable = 'off';
                app.txtHelpCompile.Visible = 'off';
            end
        end

        % Value changed function: btnHelpProcess
        function btnHelpProcessValueChanged(app, event)
            value = app.btnHelpProcess.Value;
            if value == 1
                app.txtHelpProcess.Enable = 'on';
                app.txtHelpProcess.Visible = 'on';
            else
                app.txtHelpProcess.Enable = 'off';
                app.txtHelpProcess.Visible = 'off';
            end
        end

        % Value changed function: ddImageType
        function ddImageTypeValueChanged(app, event)
            paramSave(app, 'Image_type', app.ddImageType.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes = uiaxes(app.UIFigure);
            app.axes.XAxisLocation = 'origin';
            app.axes.XTick = [];
            app.axes.XTickLabel = {};
            app.axes.YAxisLocation = 'origin';
            app.axes.YTick = [];
            app.axes.YTickLabel = {};
            app.axes.ZTick = [];
            app.axes.ZTickLabel = '';
            app.axes.Visible = 'off';
            app.txtFeedback = uilabel(app.UIFigure);
            app.txtFeedback.Text = '';
            app.btnChooseStudy = uibutton(app.UIFigure, 'push');
            app.btnChooseStudy.ButtonPushedFcn = createCallbackFcn(app, @btnChooseStudyButtonPushed, true);
            app.btnChooseStudy.Text = 'Study Folder';
            app.ddSubs = uidropdown(app.UIFigure);
            app.ddSubs.Items = {};
            app.ddSubs.ValueChangedFcn = createCallbackFcn(app, @ddSubsValueChanged, true);
            app.ddSubs.Value = {};
            app.txtStudyFolder = uieditfield(app.UIFigure, 'text');
            app.txtStudyFolder.ValueChangedFcn = createCallbackFcn(app, @txtStudyFolderValueChanged, true);
            app.txtStudyFolder.Value = 'C:\Users\';
            app.ddImages = uidropdown(app.UIFigure);
            app.ddImages.Items = {};
            app.ddImages.ValueChangedFcn = createCallbackFcn(app, @ddImagesValueChanged, true);
            app.ddImages.Value = {};
            app.chkAutoLoad = uicheckbox(app.UIFigure);
            app.chkAutoLoad.ValueChangedFcn = createCallbackFcn(app, @chkAutoLoadValueChanged, true);
            app.chkAutoLoad.Text = '  Auto Load';
            app.chkAutoLoad.Value = true;
            app.btnLoad = uibutton(app.UIFigure, 'push');
            app.btnLoad.ButtonPushedFcn = createCallbackFcn(app, @btnLoadButtonPushed, true);
            app.btnLoad.Enable = 'off';
            app.btnLoad.Text = 'Load';
            app.lblSubjects = uilabel(app.UIFigure);
            app.lblSubjects.Text = 'Subjects:';
            app.lblImages = uilabel(app.UIFigure);
            app.lblImages.Text = 'Images:';
            app.btnZoom = uibutton(app.UIFigure, 'state');
            app.btnZoom.ValueChangedFcn = createCallbackFcn(app, @btnZoomValueChanged, true);
            app.btnZoom.Icon = 'zoom2.png';
            app.btnZoom.Text = '';
            app.btnPan = uibutton(app.UIFigure, 'state');
            app.btnPan.ValueChangedFcn = createCallbackFcn(app, @btnPanValueChanged, true);
            app.btnPan.Icon = 'Picture4.svg';
            app.btnPan.Text = '';
            app.btnHome = uibutton(app.UIFigure, 'state');
            app.btnHome.ValueChangedFcn = createCallbackFcn(app, @btnHomeValueChanged, true);
            app.btnHome.Icon = 'logo-clipart-home-13.png';
            app.btnHome.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'scout2.png';
            app.panSettings = uipanel(app.UIFigure);
            app.panSettings.Title = 'Image Settings';
            app.btnCrop = uibutton(app.panSettings, 'push');
            app.btnCrop.ButtonPushedFcn = createCallbackFcn(app, @btnCropButtonPushed, true);
            app.btnCrop.Text = 'Set Crop';
            app.chkViewFull = uicheckbox(app.panSettings);
            app.chkViewFull.ValueChangedFcn = createCallbackFcn(app, @chkViewFullValueChanged, true);
            app.chkViewFull.Text = 'View Full';
            app.btnCorners = uibutton(app.panSettings, 'push');
            app.btnCorners.ButtonPushedFcn = createCallbackFcn(app, @btnCornersPushed, true);
            app.btnCorners.Text = 'Set Corners';
            app.btnHelpCorners = uibutton(app.panSettings, 'state');
            app.btnHelpCorners.ValueChangedFcn = createCallbackFcn(app, @btnHelpCornersValueChanged, true);
            app.btnHelpCorners.Text = {'?'; ''};
            app.ddImageType = uidropdown(app.panSettings);
            app.ddImageType.Items = {'Longitudinal', 'Transverse'};
            app.ddImageType.ValueChangedFcn = createCallbackFcn(app, @ddImageTypeValueChanged, true);
            app.ddImageType.Value = 'Transverse';
            app.txtHelpCorners = uitextarea(app.panSettings);
            app.txtHelpCorners.Editable = 'off';
            app.txtHelpCorners.Visible = 'off';
            app.txtHelpCorners.Value = {'Set the top left and top right corner of the image. These positions can be used to correct echo intensity for the amount and quality of tissue above each region or subregion of cartilage.'};
            app.panEnhance = uipanel(app.UIFigure);
            app.panEnhance.Title = 'Image Enhancement';
            app.ContrastSliderLabel = uilabel(app.panEnhance);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.sldContrast = uislider(app.panEnhance);
            app.sldContrast.Limits = [0 200];
            app.sldContrast.MajorTicks = [];
            app.sldContrast.ValueChangedFcn = createCallbackFcn(app, @sldContrastValueChanged, true);
            app.sldContrast.MinorTicks = [];
            app.sldContrast.Value = 50;
            app.chkViewOriginal = uicheckbox(app.panEnhance);
            app.chkViewOriginal.ValueChangedFcn = createCallbackFcn(app, @chkViewOriginalValueChanged, true);
            app.chkViewOriginal.Text = 'Reset';
            app.ddFilters = uidropdown(app.panEnhance);
            app.ddFilters.Items = {'None', 'Auto Adjust Contrast', 'Butterworth', 'Auto+Butterworth'};
            app.ddFilters.ValueChangedFcn = createCallbackFcn(app, @ddFiltersValueChanged, true);
            app.ddFilters.Value = 'None';
            app.lblFilters = uilabel(app.panEnhance);
            app.lblFilters.Text = 'Filters';
            app.btnHelpImage = uibutton(app.panEnhance, 'state');
            app.btnHelpImage.ValueChangedFcn = createCallbackFcn(app, @btnHelpImageValueChanged, true);
            app.btnHelpImage.Text = {'?'; ''};
            app.txtHelpImage = uitextarea(app.panEnhance);
            app.txtHelpImage.Editable = 'off';
            app.txtHelpImage.Visible = 'off';
            app.txtHelpImage.Value = {'Enhance contrast or apply filters to image to better define the echogenic lines of the cartilage contours. Note: echo intensity is taken from the unfiltered grayscale image. '};
            app.panResults = uipanel(app.UIFigure);
            app.panResults.Title = 'Display Results';
            app.listMeasures = uilistbox(app.panResults);
            app.listMeasures.Items = {'Contours', 'Regions', 'Intercondylar Notch', 'Image Corners'};
            app.listMeasures.Multiselect = 'on';
            app.listMeasures.ValueChangedFcn = createCallbackFcn(app, @listMeasuresValueChanged, true);
            app.listMeasures.Value = {'Contours'};
            app.btnMeasuresAll = uibutton(app.panResults, 'push');
            app.btnMeasuresAll.ButtonPushedFcn = createCallbackFcn(app, @btnMeasuresAllButtonPushed, true);
            app.btnMeasuresAll.Text = 'View All';
            app.btnMeasuresNone = uibutton(app.panResults, 'push');
            app.btnMeasuresNone.ButtonPushedFcn = createCallbackFcn(app, @btnMeasuresNoneButtonPushed, true);
            app.btnMeasuresNone.Text = 'View None';
            app.btnColors = uibutton(app.panResults, 'push');
            app.btnColors.ButtonPushedFcn = createCallbackFcn(app, @btnColorsButtonPushed, true);
            app.btnColors.Text = '';
            app.panSave = uipanel(app.UIFigure);
            app.panSave.Title = 'Process, Save & Compile';
            app.btnCompile = uibutton(app.panSave, 'push');
            app.btnCompile.ButtonPushedFcn = createCallbackFcn(app, @btnCompilePushed, true);
            app.btnCompile.Text = 'Compile data';
            app.btnSave = uibutton(app.panSave, 'push');
            app.btnSave.ButtonPushedFcn = createCallbackFcn(app, @btnSaveButtonPushed, true);
            app.btnSave.Text = 'Process & Save';
            app.ddProcess = uidropdown(app.panSave);
            app.ddProcess.Items = {'Single File', 'All (including previously processed)', 'All except previously processed', 'All except previously processed w/ same parameters'};
            app.ddProcess.ItemsData = [1 2 3 4];
            app.ddProcess.Value = 1;
            app.btnHelpCompile = uibutton(app.panSave, 'state');
            app.btnHelpCompile.ValueChangedFcn = createCallbackFcn(app, @btnHelpCompileValueChanged, true);
            app.btnHelpCompile.Text = {'?'; ''};
            app.btnHelpProcess = uibutton(app.panSave, 'state');
            app.btnHelpProcess.ValueChangedFcn = createCallbackFcn(app, @btnHelpProcessValueChanged, true);
            app.btnHelpProcess.Text = {'?'; ''};
            app.txtHelpCompile = uitextarea(app.panSave);
            app.txtHelpCompile.Editable = 'off';
            app.txtHelpCompile.Enable = 'off';
            app.txtHelpCompile.Visible = 'off';
            app.txtHelpCompile.Value = {'This is another convenience option to compile all data from images that have been processed into 2 maste rfiles saved in the study folder. One file stores all data from every image. Another file averages data for images from the same subject and limb for cases of collecting multiple images on the same subject (i.e. when researchers collect 3 images on the same subject/limb to average)'};
            app.txtHelpProcess = uitextarea(app.panSave);
            app.txtHelpProcess.Editable = 'off';
            app.txtHelpProcess.Enable = 'off';
            app.txtHelpProcess.Visible = 'off';
            app.txtHelpProcess.Value = {'These are convenience features to 1) process a single file 2) process all files in study 3) only process files that havent been processed or 4) make sure all images in study were processed and with the same parameters. if they were not processed with the same parameters, they will be re-processed and if they were, they will be skipped to save time. More details below:'; ''; '"Single File" will process current file only. '; ''; '"All (including previously processed)" will process or re-process all images in study folder that have contours drawn. This ensures all processing parameters are the same. '; ''; '"All except previously processed" will process all images in study folder that have not yet been processed. This will skip files that have already been processed ignoring the parameters.'; ''; '"All except previously processed w/ same parameters" will check to make sure all previously processed images were processed with same parameters. If they were processed with the same parameters, they will be skipped. If they was not processed with the same parameters, it will be re-processed. This ensures all processing parameters are the same without having to reprocess data.'};
            app.panROI = uipanel(app.UIFigure);
            app.panROI.Title = 'ROI Options';
            app.ddNumContours = uidropdown(app.panROI);
            app.ddNumContours.Items = {'Two', 'Three'};
            app.ddNumContours.Value = 'Two';
            app.btnDraw = uibutton(app.panROI, 'push');
            app.btnDraw.ButtonPushedFcn = createCallbackFcn(app, @btnDrawButtonPushed, true);
            app.btnDraw.Text = 'Draw ';
            app.lblContours = uilabel(app.panROI);
            app.lblContours.Text = 'Contours';
            app.btnAdjustContours = uibutton(app.panROI, 'push');
            app.btnAdjustContours.ButtonPushedFcn = createCallbackFcn(app, @btnAdjustContoursButtonPushed, true);
            app.btnAdjustContours.Text = 'Adjust Contours';
            app.btnAdjustNotch = uibutton(app.panROI, 'push');
            app.btnAdjustNotch.ButtonPushedFcn = createCallbackFcn(app, @btnAdjustNotchPushed, true);
            app.btnAdjustNotch.Text = 'Adjust Notch';
            app.btnAccept = uibutton(app.UIFigure, 'state');
            app.btnAccept.ValueChangedFcn = createCallbackFcn(app, @btnAcceptValueChanged, true);
            app.btnAccept.Visible = 'off';
            app.btnAccept.Text = 'Accept';
            app.panMeasureOpt = uipanel(app.UIFigure);
            app.panMeasureOpt.Title = 'Measurement Options';
            app.btnMeasure = uibutton(app.panMeasureOpt, 'push');
            app.btnMeasure.ButtonPushedFcn = createCallbackFcn(app, @btnMeasureButtonPushed, true);
            app.btnMeasure.Text = 'Measure';
            app.IntercondylarROILabel = uilabel(app.panMeasureOpt);
            app.IntercondylarROILabel.Text = 'Intercondylar ROI (%)';
            app.txtInterROIPct = uieditfield(app.panMeasureOpt, 'numeric');
            app.txtInterROIPct.Limits = [0 50];
            app.txtInterROIPct.Value = 25;
            app.NumberofSubregionsLabel = uilabel(app.panMeasureOpt);
            app.NumberofSubregionsLabel.Text = 'Number of Subregions';
            app.txtNumROI = uieditfield(app.panMeasureOpt, 'numeric');
            app.txtNumROI.Limits = [1 10];
            app.txtNumROI.Value = 3;
            app.ddLengthOpt = uidropdown(app.panMeasureOpt);
            app.ddLengthOpt.Items = {'Minimum', '10 mm'};
            app.ddLengthOpt.Value = 'Minimum';
            app.lblMLlength = uilabel(app.panMeasureOpt);
            app.lblMLlength.Text = 'M/L Length';
            app.btnHelpMLlength = uibutton(app.panMeasureOpt, 'state');
            app.btnHelpMLlength.ValueChangedFcn = createCallbackFcn(app, @btnHelpMLlengthValueChanged, true);
            app.btnHelpMLlength.Text = {'?'; ''};
            app.lblSpeed = uilabel(app.panMeasureOpt);
            app.lblSpeed.Text = 'Speed of Sound Correction Factor';
            app.btnHelpSpeed = uibutton(app.panMeasureOpt, 'state');
            app.btnHelpSpeed.ValueChangedFcn = createCallbackFcn(app, @btnHelpSpeedValueChanged, true);
            app.btnHelpSpeed.Text = {'?'; ''};
            app.txtSpeed = uieditfield(app.panMeasureOpt, 'numeric');
            app.txtSpeed.Limits = [0 Inf];
            app.txtSpeed.Value = 1.1;
            app.txtHelpMLlength = uitextarea(app.panMeasureOpt);
            app.txtHelpMLlength.Editable = 'off';
            app.txtHelpMLlength.Enable = 'off';
            app.txtHelpMLlength.Visible = 'off';
            app.txtHelpMLlength.Value = {'Choose the method to determine the medial and lateral ROI''s are equal in length. '; ''; 'Minimum: M/L lengths will be constrained to the contour of the shortest length (default). '; ''; '10 mm: both M/L lengths will be constrained to 10 millimeters. If both ROI''s are not atleast10mm, the minimum length of the M/L ROI will be used'};
            app.txtHelpSpeed = uitextarea(app.panMeasureOpt);
            app.txtHelpSpeed.Editable = 'off';
            app.txtHelpSpeed.Enable = 'off';
            app.txtHelpSpeed.Visible = 'off';
            app.txtHelpSpeed.Value = {'The speed of sound differs by ultrasound unit and the type of tissue being imaged. A suggested factor of 1.10 has been used to correct for the speed of sound in biological tissue vs. speed of sound in cartilage [(1696 (m/s) / 1540 (m/s)]. However, it is important to note that this correction assumes that the speed of sound in cartilage and tissue is uniform when it is anisotropic and a wide range of values have been reported in the literature.  This value is used to correct all thickness measures and in Snells law to determine the angle of refraction. See user guide for more information.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scout_v5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end